classdef self_balancing_robot_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Panel_ControlAction    matlab.ui.container.Panel
        LabelGauge_RightWheel  matlab.ui.control.Label
        LabelGauge_LeftWheel   matlab.ui.control.Label
        Gauge_RightWheel       matlab.ui.control.LinearGauge
        Gauge_LeftWheel        matlab.ui.control.LinearGauge
        Panel_UserCommands     matlab.ui.container.Panel
        Gauge_Yaw              matlab.ui.control.LinearGauge
        LabelGauge_Yaw         matlab.ui.control.Label
        LabelGauge_Throttle    matlab.ui.control.Label
        Gauge_Throttle         matlab.ui.control.LinearGauge
        Panel_Serial           matlab.ui.container.Panel
        DropDown_SerialPorts   matlab.ui.control.DropDown
        PortsDropDownLabel     matlab.ui.control.Label
        Button_SerialConnect   matlab.ui.control.Button
        Lamp_SerialStatus      matlab.ui.control.Lamp
        StatusLampLabel        matlab.ui.control.Label
        Label_Tittle           matlab.ui.control.Label
        Image_ETSIDI           matlab.ui.control.Image
        Image_upm              matlab.ui.control.Image
        Graph_Roll             matlab.ui.control.UIAxes
        Graph_Yaw              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serial_port;
        connect_status;
        first_connection;
        data_idx;
        serial_commandThrottle;
        serial_commandYaw;
        scaled_commandYaw;
        scaled_commandThrottle;
    end
    
    methods (Access = private)
        
        
        function result = map(app,value, fromLow, fromHigh, toLow, toHigh)
            result = (value - fromLow) * (toHigh - toLow) / (fromHigh - fromLow) + toLow;
        end

        function result = scale(app,value)
            if value>1500+250
                result = app.map(value,1500,2000,0,100);
            elseif value<1500-250
                result = app.map(value,900,1500,-100,0);
            else
                result = 0;
            end
           
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Complete the combo box with the available ports
            portList = serialportlist;
            app.DropDown_SerialPorts.Items = portList;

            % Configure the gauge
            app.Gauge_Throttle.Limits = [-100 100];
            app.Gauge_Yaw.Limits = [-100 100];

            % Configure the graphic
            %ylim(app.UIAxes, [-360, 360]);
            %xlim(app.UIAxes, [0, 10]);

            % Initialize the lamp
            app.Lamp_SerialStatus.Color = 'r';

            % Initialize attributes
            app.first_connection = true;
            app.connect_status = false;
            app.data_idx=1;
            app.serial_commandThrottle = 0;
            app.serial_commandYaw = 0;
        end

        % Button pushed function: Button_SerialConnect
        function Button_SerialConnectPushed(app, event)
                        % Activate status to true
            app.connect_status = true;
            app.Lamp_SerialStatus.Color = 'g';

            if(app.first_connection == true)
                % Connect to the serial port selected in the comboBox
                app.serial_port=serialport(app.DropDown_SerialPorts.Value,115200);
                configureTerminator(app.serial_port,"CR/LF");
                app.first_connection = false;
            end

            % Initialize the data for the plot before entering the loop
            angles = []; % Stores angle values for the plot
            idx = [];    % Stores indices (time or iterations)
            
            while(app.connect_status == true)
                % Read the serial port data
                flush(app.serial_port);
                serial_data = readline(app.serial_port);
            
                % Split the data by the comma
                split_serial_data = split(serial_data, ',');
            
                % Check if the data was properly split into two parts
                if numel(split_serial_data) == 3
                    % Convert and assign the data
                    app.serial_commandYaw = str2double(split_serial_data{1});  
                    app.serial_commandThrottle = str2double(split_serial_data{2});
            
                    % Verify if the data is valid
                    if ~isnan(app.serial_commandThrottle) && ~isnan(app.serial_commandYaw)

                        % Scale data input from [900-2000] to [-100-100]
                        app.scaled_commandThrottle = app.scale(app.serial_commandThrottle);
                        app.scaled_commandYaw = app.scale(app.serial_commandYaw);

                        % Update the Gauges
                        if isfinite(app.scaled_commandYaw)
                            app.Gauge_Yaw.Value = app.scaled_commandYaw;
                        else
                            app.Gauge_Yaw.Value = 0; % Default value for invalid data
                            warning('Invalid control action value received. Setting gauge to default value (0).');
                        end

                        if isfinite(app.scaled_commandThrottle)
                            app.Gauge_Throttle.Value = app.scaled_commandThrottle;
                        else
                            app.Gauge_Throttle.Value = 0; % Default value for invalid data
                            warning('Invalid control action value received. Setting gauge to default value (0).');
                        end
                    else
                        % Log a warning if the received data is invalid
                        warning('Invalid numeric data received: %s', serial_data);
                    end
                else
                    % Log a warning if the data is not in the expected format
                    warning('Invalid serial data format: %s', serial_data);
                end
            
                % Pause to simulate the sampling frequency
                pause(0.1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graph_Yaw = uiaxes(app.UIFigure);
            xlabel(app.Graph_Yaw, 'Time [s]')
            ylabel(app.Graph_Yaw, 'Yaw [deg]')
            zlabel(app.Graph_Yaw, 'Z')
            app.Graph_Roll = uiaxes(app.UIFigure);
            xlabel(app.Graph_Roll, 'Time [s]')
            ylabel(app.Graph_Roll, 'Pitch [deg]')
            zlabel(app.Graph_Roll, 'Z')
            app.Image_upm = uiimage(app.UIFigure);
            app.Image_upm.ImageSource = fullfile(pathToMLAPP, 'upm_logo.png');
            app.Image_ETSIDI = uiimage(app.UIFigure);
            app.Image_ETSIDI.ImageSource = fullfile(pathToMLAPP, 'etsidi.png');
            app.Label_Tittle = uilabel(app.UIFigure);
            app.Label_Tittle.Text = 'SELF-BALANCING ROBOT INTERFACE';
            app.Panel_Serial = uipanel(app.UIFigure);
            app.Panel_Serial.Title = 'Serial Connection';
            app.StatusLampLabel = uilabel(app.Panel_Serial);
            app.StatusLampLabel.Text = 'Status';
            app.Lamp_SerialStatus = uilamp(app.Panel_Serial);
            app.Button_SerialConnect = uibutton(app.Panel_Serial, 'push');
            app.Button_SerialConnect.ButtonPushedFcn = createCallbackFcn(app, @Button_SerialConnectPushed, true);
            app.Button_SerialConnect.Text = 'Connect';
            app.PortsDropDownLabel = uilabel(app.Panel_Serial);
            app.PortsDropDownLabel.Text = 'Ports';
            app.DropDown_SerialPorts = uidropdown(app.Panel_Serial);
            app.Panel_UserCommands = uipanel(app.UIFigure);
            app.Panel_UserCommands.Title = 'User Commands';
            app.Gauge_Throttle = uigauge(app.Panel_UserCommands, 'linear');
            app.Gauge_Throttle.Limits = [-100 100];
            app.Gauge_Throttle.MajorTicks = [-100 -50 0 50 100];
            app.Gauge_Throttle.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.LabelGauge_Throttle = uilabel(app.Panel_UserCommands);
            app.LabelGauge_Throttle.Text = 'Throttle';
            app.LabelGauge_Yaw = uilabel(app.Panel_UserCommands);
            app.LabelGauge_Yaw.Text = 'Yaw';
            app.Gauge_Yaw = uigauge(app.Panel_UserCommands, 'linear');
            app.Gauge_Yaw.Limits = [-100 100];
            app.Gauge_Yaw.MajorTicks = [-100 -50 0 50 100];
            app.Gauge_Yaw.MajorTickLabels = {'-100', '-50', '0', '50', '100'};
            app.Panel_ControlAction = uipanel(app.UIFigure);
            app.Panel_ControlAction.Title = 'Control Action';
            app.Gauge_LeftWheel = uigauge(app.Panel_ControlAction, 'linear');
            app.Gauge_LeftWheel.Limits = [-255 255];
            app.Gauge_LeftWheel.MajorTicks = [-255 -125 0 125 255];
            app.Gauge_RightWheel = uigauge(app.Panel_ControlAction, 'linear');
            app.Gauge_RightWheel.Limits = [-255 255];
            app.Gauge_RightWheel.MajorTicks = [-255 -125 0 125 255];
            app.LabelGauge_LeftWheel = uilabel(app.Panel_ControlAction);
            app.LabelGauge_LeftWheel.Text = 'Left Wheel';
            app.LabelGauge_RightWheel = uilabel(app.Panel_ControlAction);
            app.LabelGauge_RightWheel.Text = 'Right Wheel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = self_balancing_robot_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end